classdef PairMovie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TimeStampsLabel            matlab.ui.control.Label
        TimeStampsDropDown         matlab.ui.control.DropDown
        TimeAddButton              matlab.ui.control.Button
        LeftFrameButton            matlab.ui.control.Button
        RightFrameButton           matlab.ui.control.Button
        LeftFrameFileNameText      matlab.ui.control.EditField
        RightFrameFileNameText     matlab.ui.control.EditField
        TimeSliderLabel            matlab.ui.control.Label
        TimeSlider                 matlab.ui.control.Slider
        LeftInitText               matlab.ui.control.EditField
        RightInitText              matlab.ui.control.EditField
        IndexOffsetLabel           matlab.ui.control.Label
        TotFrameText               matlab.ui.control.EditField
        TotolframesLabel           matlab.ui.control.Label
        LowerClipSliderLabel       matlab.ui.control.Label
        LowerClipSlider            matlab.ui.control.Slider
        UpperClipSliderLabel       matlab.ui.control.Label
        UpperClipSlider            matlab.ui.control.Slider
        TimeDelButton              matlab.ui.control.Button
        XSliderLabel               matlab.ui.control.Label
        XTimePosSlider             matlab.ui.control.Slider
        YSliderLabel               matlab.ui.control.Label
        YTimePosSlider             matlab.ui.control.Slider
        TimeStepEditFieldLabel     matlab.ui.control.Label
        TimeStepText               matlab.ui.control.EditField
        SizeLabel                  matlab.ui.control.Label
        TimeFontSizeSlider         matlab.ui.control.Slider
        LegendsDropDownLabel       matlab.ui.control.Label
        LegendsDropDown            matlab.ui.control.DropDown
        LegendAddButton            matlab.ui.control.Button
        LegendDelButton            matlab.ui.control.Button
        XSliderLabel_2             matlab.ui.control.Label
        XLegendPosSlider           matlab.ui.control.Slider
        YSliderLabel_2             matlab.ui.control.Label
        YLegendPosSlider           matlab.ui.control.Slider
        SizeLabel_2                matlab.ui.control.Label
        LegendFontSizeSlider       matlab.ui.control.Slider
        LegendText                 matlab.ui.control.EditField
        AdjustimageconstrastLabel  matlab.ui.control.Label
        AddtimestampsLabel         matlab.ui.control.Label
        AddlegendsLabel            matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        PreviewAxes                matlab.ui.control.UIAxes
        RenderButton               matlab.ui.control.Button
        MoviePreviewLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        left_frame_filepath % Description
        left_frame_filename % Description
        right_frame_filepath
        right_frame_filename
        
        movie_row
        movie_col
        movie_frame
        lower_clip
        upper_clip
        
        time_stamp_num
        time_stamp_info
        target_time_stamp_ind
        
        legend_num
        legend_info
        target_legend_ind

    end
    
    methods (Access = private)
        
        
        function updatePreview(app)
            preview = imadjust(app.movie_frame,[app.lower_clip, app.upper_clip]);
            imshow( preview, 'Parent', app.PreviewAxes); app.PreviewAxes.Visible = 0;
            frameInd = round(app.TimeSlider.Value)-1;
            for tt = 1:app.time_stamp_num
                timeStampStr = sprintf("%6.2f ms", frameInd*app.time_stamp_info(tt).step);
                tex = text(app.PreviewAxes, app.time_stamp_info(tt).xpos,app.time_stamp_info(tt).ypos,timeStampStr);
                tex.FontSize = app.time_stamp_info(tt).fontsize;
                tex.Color = 'white';
                tex.FontWeight = 'bold';
            end
            for ll =1:app.legend_num
                tex = text(app.PreviewAxes, app.legend_info(ll).xpos, app.legend_info(ll).ypos, app.legend_info(ll).textstr);
                tex.FontSize = app.legend_info(ll).fontsize;
                tex.Color = 'white';
                tex.FontWeight = 'bold';                
            end
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('./RUN/settings.mat')
                load('./RUN/settings.mat');
                app.left_frame_filepath = filepath1;
                app.right_frame_filepath = filepath2;
            else
                app.left_frame_filepath = './';
                app.right_frame_filepath = './';
            end
            app.left_frame_filename = 0;
            app.right_frame_filename = 0;
            app.TotFrameText.Value = '1';
            app.TimeSlider.Limits = [0, 1];
            app.LeftInitText.Value = '0';
            app.RightInitText.Value = '0';
            app.lower_clip = 0.001;
            app.upper_clip = 0.04;
            app.LowerClipSlider.Value = app.lower_clip;
            app.UpperClipSlider.Value = app.upper_clip;
            
            app.time_stamp_num = 0;
            app.time_stamp_info = struct;
            app.target_time_stamp_ind = 0;
            app.TimeStepText.Value = '1';
            app.XTimePosSlider.Value = 0.1;
            app.YTimePosSlider.Value = 0.1;
            
            app.legend_num = 0;
            app.legend_info = struct;
            app.target_legend_ind = 0;
            app.LegendText.Value = 'Template';
            app.XLegendPosSlider.Value = 0.1;
            app.YLegendPosSlider.Value = 0.1;            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {626, 626};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {445, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~exist('./RUN')
                mkdir('./RUN');
            end
            filepath1 = app.left_frame_filepath;
            filepath2 = app.right_frame_filepath;
            save('./RUN/settings.mat', 'filepath1', 'filepath2');
            delete(app)
        end

        % Button pushed function: LeftFrameButton
        function LeftFrameButtonPushed(app, event)
            [app.left_frame_filename, app.left_frame_filepath] = uigetfile({'*.tif; *.tiff'}, 'Pick Movie Left', app.left_frame_filepath);
            if app.left_frame_filepath == 0
                app.left_frame_filepath = './';
            end
            app.LeftFrameFileNameText.Value = app.left_frame_filename;
        end

        % Button pushed function: RightFrameButton
        function RightFrameButtonPushed(app, event)
            [app.right_frame_filename, app.right_frame_filepath] = uigetfile({'*.tif; *.tiff'}, 'Pick Movie Right', app.right_frame_filepath);
            if app.right_frame_filepath == 0
                app.right_frame_filepath = './';
            end
            app.RightFrameFileNameText.Value = app.right_frame_filename;            
        end

        % Value changed function: TotFrameText
        function TotFrameTextValueChanged(app, event)
            value = round(str2num(app.TotFrameText.Value));
            app.TimeSlider.Limits = [0, value];
%             disp(size(value))
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = round(event.Value);
%             disp(app.left_frame_filename)
            if sum(app.left_frame_filename) == 0 || sum(app.right_frame_filename) == 0
                disp("Missing file for one or both movies. Please re-select movie file.")
            else
                try 
                    frame1 = imread(fullfile(app.left_frame_filepath, app.left_frame_filename), round(changingValue+str2num(app.LeftInitText.Value)));
                    frame2 = imread(fullfile(app.right_frame_filepath, app.right_frame_filename), round(changingValue+str2num(app.RightInitText.Value)));
                    [row1, col1] = size(frame1);
                    [row2, col2] = size(frame2);
                    if row1~=row2 || col1~=col2
                        disp('Left and right movies have unmatched frame size.');
                    else
                        app.movie_frame = [frame1, frame2];
                        app.movie_row = row1;
                        app.movie_col = col1 + col2;
                        updatePreview(app);
                        
                    end
                catch ME
                    if strcmp(ME.identifier, 'MATLAB:imagesci:rtifc:invalidDirIndex')
                        disp('Frame index out of range. Please change total frame number.')
                    end
                end                              
            end
        end

        % Value changing function: LowerClipSlider
        function LowerClipSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue < app.upper_clip
                app.lower_clip = changingValue;
            end
            updatePreview(app);
        end

        % Value changing function: UpperClipSlider
        function UpperClipSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue > app.lower_clip
                app.upper_clip = changingValue;
            end
            updatePreview(app);
        end

        % Button pushed function: TimeAddButton
        function TimeAddButtonPushed(app, event)
            app.time_stamp_num = app.time_stamp_num + 1;
            app.TimeStampsDropDown.Items{end+1} = sprintf('%d', app.time_stamp_num); 
            app.time_stamp_info(app.time_stamp_num).xpos = round(app.XTimePosSlider.Value * app.movie_col);
            app.time_stamp_info(app.time_stamp_num).ypos = round(app.YTimePosSlider.Value * app.movie_row);
            app.time_stamp_info(app.time_stamp_num).step = str2double(app.TimeStepText.Value);
            app.time_stamp_info(app.time_stamp_num).fontsize = round(app.TimeFontSizeSlider.Value);
        end

        % Button pushed function: TimeDelButton
        function TimeDelButtonPushed(app, event)
            app.time_stamp_num = app.time_stamp_num - 1;
            app.TimeStampsDropDown.Items = {app.TimeStampsDropDown.Items{1:end-1}};
            app.time_stamp_info(app.time_stamp_num+1) = [];
        end

        % Value changed function: TimeStampsDropDown
        function TimeStampsDropDownValueChanged(app, event)
            value = app.TimeStampsDropDown.Value;
            app.target_time_stamp_ind = str2num(value);
            app.XTimePosSlider.Value = app.time_stamp_info(app.target_time_stamp_ind).xpos / app.movie_col;
            app.YTimePosSlider.Value = app.time_stamp_info(app.target_time_stamp_ind).ypos / app.movie_row;
            app.TimeStepText.Value = num2str(app.time_stamp_info(app.target_time_stamp_ind).step);
            app.TimeFontSizeSlider.Value = app.time_stamp_info(app.target_time_stamp_ind).fontsize;
            updatePreview(app);
        end

        % Value changing function: XTimePosSlider
        function XTimePosSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.target_time_stamp_ind > 0
                app.time_stamp_info(app.target_time_stamp_ind).xpos = round(changingValue * app.movie_col);
            end
            updatePreview(app);
        end

        % Value changing function: YTimePosSlider
        function YTimePosSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.target_time_stamp_ind > 0
                app.time_stamp_info(app.target_time_stamp_ind).ypos = round(changingValue * app.movie_row);
            end
            updatePreview(app);
        end

        % Value changed function: TimeStepText
        function TimeStepTextValueChanged(app, event)
            value = str2double(app.TimeStepText.Value);
            if app.target_time_stamp_ind > 0
                app.time_stamp_info(app.target_time_stamp_ind).step = value;
            end
            updatePreview(app);
        end

        % Value changing function: TimeFontSizeSlider
        function TimeFontSizeSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.target_time_stamp_ind > 0
                app.time_stamp_info(app.target_time_stamp_ind).fontsize = round(changingValue);
            end
            updatePreview(app);            
        end

        % Value changed function: LegendsDropDown
        function LegendsDropDownValueChanged(app, event)
            value = app.LegendsDropDown.Value;
            app.target_legend_ind = str2num(value);
            app.XLegendPosSlider.Value = app.legend_info(app.target_legend_ind).xpos / app.movie_col;
            app.YLegendPosSlider.Value = app.legend_info(app.target_legend_ind).ypos / app.movie_row;
            app.LegendFontSizeSlider.Value = app.legend_info(app.target_legend_ind).fontsize;
            app.LegendText.Value = app.legend_info(app.target_legend_ind).textstr;
            updatePreview(app);            
        end

        % Button pushed function: LegendAddButton
        function LegendAddButtonPushed(app, event)
            app.legend_num = app.legend_num + 1;
            app.LegendsDropDown.Items{end+1} = sprintf('%d', app.legend_num); 
            app.legend_info(app.legend_num).xpos = round(app.XLegendPosSlider.Value * app.movie_col);
            app.legend_info(app.legend_num).ypos = round(app.YLegendPosSlider.Value * app.movie_row);
            app.legend_info(app.legend_num).textstr = app.LegendText.Value;
            app.legend_info(app.legend_num).fontsize = round(app.LegendFontSizeSlider.Value);            
        end

        % Button pushed function: LegendDelButton
        function LegendDelButtonPushed(app, event)
            app.legend_num = app.legend_num - 1;
            app.LegendsDropDown.Items = {app.LegendsDropDown.Items{1:end-1}};
            app.legend_info(app.legend_num+1) = [];            
        end

        % Value changing function: XLegendPosSlider
        function XLegendPosSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.target_legend_ind > 0
                app.legend_info(app.target_legend_ind).xpos = round(changingValue * app.movie_col);
            end
            updatePreview(app);
        end

        % Value changing function: YLegendPosSlider
        function YLegendPosSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.target_legend_ind > 0
                app.legend_info(app.target_legend_ind).ypos = round(changingValue * app.movie_row);
            end
            updatePreview(app);
        end

        % Value changed function: LegendText
        function LegendTextValueChanged(app, event)
            value = app.LegendText.Value;
            if app.target_legend_ind > 0
                app.legend_info(app.target_legend_ind).textstr = value;
            end
            updatePreview(app);
        end

        % Value changing function: LegendFontSizeSlider
        function LegendFontSizeSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.target_legend_ind > 0
                app.legend_info(app.target_legend_ind).fontsize = round(changingValue);
            end
            updatePreview(app); 
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
%             render_frame = 123
            if ~exist('./movie_export')
                mkdir('./movie_export');
            end
%             app.TimeSlider.Limits
            for render_frame = 1:round(app.TimeSlider.Limits(2))
                app.TimeSlider.Value = render_frame;
                frame1 = imread(fullfile(app.left_frame_filepath, app.left_frame_filename), round(render_frame+str2num(app.LeftInitText.Value)));
                frame2 = imread(fullfile(app.right_frame_filepath, app.right_frame_filename), round(render_frame+str2num(app.RightInitText.Value)));
                app.movie_frame = [frame1, frame2];        
                updatePreview(app);
                exportgraphics(app.PreviewAxes, sprintf('./movie_export/SuppMov%03d.png', render_frame));
            end
                                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TimeStampsLabel = uilabel(app.LeftPanel);
            app.TimeStampsLabel.Text = 'Time Stamps';
            app.TimeStampsDropDown = uidropdown(app.LeftPanel);
            app.TimeStampsDropDown.Items = {'None'};
            app.TimeStampsDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeStampsDropDownValueChanged, true);
            app.TimeStampsDropDown.Value = 'None';
            app.TimeAddButton = uibutton(app.LeftPanel, 'push');
            app.TimeAddButton.ButtonPushedFcn = createCallbackFcn(app, @TimeAddButtonPushed, true);
            app.TimeAddButton.Text = 'Add';
            app.LeftFrameButton = uibutton(app.LeftPanel, 'push');
            app.LeftFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LeftFrameButtonPushed, true);
            app.LeftFrameButton.Text = 'Left Frame';
            app.RightFrameButton = uibutton(app.LeftPanel, 'push');
            app.RightFrameButton.ButtonPushedFcn = createCallbackFcn(app, @RightFrameButtonPushed, true);
            app.RightFrameButton.Text = {'Right Frame'; ''};
            app.LeftFrameFileNameText = uieditfield(app.LeftPanel, 'text');
            app.LeftFrameFileNameText.Editable = 'off';
            app.RightFrameFileNameText = uieditfield(app.LeftPanel, 'text');
            app.RightFrameFileNameText.Editable = 'off';
            app.TimeSliderLabel = uilabel(app.LeftPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.LeftPanel);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.LeftInitText = uieditfield(app.LeftPanel, 'text');
            app.RightInitText = uieditfield(app.LeftPanel, 'text');
            app.IndexOffsetLabel = uilabel(app.LeftPanel);
            app.IndexOffsetLabel.Text = 'Index Offset';
            app.TotFrameText = uieditfield(app.LeftPanel, 'text');
            app.TotFrameText.ValueChangedFcn = createCallbackFcn(app, @TotFrameTextValueChanged, true);
            app.TotolframesLabel = uilabel(app.LeftPanel);
            app.TotolframesLabel.Text = 'Totol frames';
            app.LowerClipSliderLabel = uilabel(app.LeftPanel);
            app.LowerClipSliderLabel.Text = 'Lower Clip';
            app.LowerClipSlider = uislider(app.LeftPanel);
            app.LowerClipSlider.Limits = [0 0.1];
            app.LowerClipSlider.ValueChangingFcn = createCallbackFcn(app, @LowerClipSliderValueChanging, true);
            app.UpperClipSliderLabel = uilabel(app.LeftPanel);
            app.UpperClipSliderLabel.Text = 'Upper Clip';
            app.UpperClipSlider = uislider(app.LeftPanel);
            app.UpperClipSlider.Limits = [0 1];
            app.UpperClipSlider.ValueChangingFcn = createCallbackFcn(app, @UpperClipSliderValueChanging, true);
            app.TimeDelButton = uibutton(app.LeftPanel, 'push');
            app.TimeDelButton.ButtonPushedFcn = createCallbackFcn(app, @TimeDelButtonPushed, true);
            app.TimeDelButton.Text = 'Del';
            app.XSliderLabel = uilabel(app.LeftPanel);
            app.XSliderLabel.Text = 'X ';
            app.XTimePosSlider = uislider(app.LeftPanel);
            app.XTimePosSlider.Limits = [0 1];
            app.XTimePosSlider.ValueChangingFcn = createCallbackFcn(app, @XTimePosSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.LeftPanel);
            app.YSliderLabel.Text = 'Y';
            app.YTimePosSlider = uislider(app.LeftPanel);
            app.YTimePosSlider.Limits = [0 1];
            app.YTimePosSlider.ValueChangingFcn = createCallbackFcn(app, @YTimePosSliderValueChanging, true);
            app.TimeStepEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepText = uieditfield(app.LeftPanel, 'text');
            app.TimeStepText.ValueChangedFcn = createCallbackFcn(app, @TimeStepTextValueChanged, true);
            app.TimeStepText.Value = '1';
            app.SizeLabel = uilabel(app.LeftPanel);
            app.SizeLabel.Text = 'Size';
            app.TimeFontSizeSlider = uislider(app.LeftPanel);
            app.TimeFontSizeSlider.Limits = [10 30];
            app.TimeFontSizeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeFontSizeSliderValueChanging, true);
            app.TimeFontSizeSlider.Value = 10;
            app.LegendsDropDownLabel = uilabel(app.LeftPanel);
            app.LegendsDropDownLabel.Text = 'Legends';
            app.LegendsDropDown = uidropdown(app.LeftPanel);
            app.LegendsDropDown.Items = {'None'};
            app.LegendsDropDown.ValueChangedFcn = createCallbackFcn(app, @LegendsDropDownValueChanged, true);
            app.LegendsDropDown.Value = 'None';
            app.LegendAddButton = uibutton(app.LeftPanel, 'push');
            app.LegendAddButton.ButtonPushedFcn = createCallbackFcn(app, @LegendAddButtonPushed, true);
            app.LegendAddButton.Text = 'Add';
            app.LegendDelButton = uibutton(app.LeftPanel, 'push');
            app.LegendDelButton.ButtonPushedFcn = createCallbackFcn(app, @LegendDelButtonPushed, true);
            app.LegendDelButton.Text = 'Del';
            app.XSliderLabel_2 = uilabel(app.LeftPanel);
            app.XSliderLabel_2.Text = 'X ';
            app.XLegendPosSlider = uislider(app.LeftPanel);
            app.XLegendPosSlider.Limits = [0 1];
            app.XLegendPosSlider.ValueChangingFcn = createCallbackFcn(app, @XLegendPosSliderValueChanging, true);
            app.YSliderLabel_2 = uilabel(app.LeftPanel);
            app.YSliderLabel_2.Text = 'Y';
            app.YLegendPosSlider = uislider(app.LeftPanel);
            app.YLegendPosSlider.Limits = [0 1];
            app.YLegendPosSlider.ValueChangingFcn = createCallbackFcn(app, @YLegendPosSliderValueChanging, true);
            app.SizeLabel_2 = uilabel(app.LeftPanel);
            app.SizeLabel_2.Text = 'Size';
            app.LegendFontSizeSlider = uislider(app.LeftPanel);
            app.LegendFontSizeSlider.Limits = [10 30];
            app.LegendFontSizeSlider.ValueChangingFcn = createCallbackFcn(app, @LegendFontSizeSliderValueChanging, true);
            app.LegendFontSizeSlider.Value = 10;
            app.LegendText = uieditfield(app.LeftPanel, 'text');
            app.LegendText.ValueChangedFcn = createCallbackFcn(app, @LegendTextValueChanged, true);
            app.LegendText.Value = 'Template';
            app.AdjustimageconstrastLabel = uilabel(app.LeftPanel);
            app.AdjustimageconstrastLabel.Text = {'Adjust image constrast'; ''};
            app.AddtimestampsLabel = uilabel(app.LeftPanel);
            app.AddtimestampsLabel.Text = 'Add time stamps';
            app.AddlegendsLabel = uilabel(app.LeftPanel);
            app.AddlegendsLabel.Text = 'Add legends';
            app.RightPanel = uipanel(app.GridLayout);
            app.PreviewAxes = uiaxes(app.RightPanel);
            title(app.PreviewAxes, '')
            xlabel(app.PreviewAxes, '')
            ylabel(app.PreviewAxes, '')
            app.RenderButton = uibutton(app.RightPanel, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.MoviePreviewLabel = uilabel(app.RightPanel);
            app.MoviePreviewLabel.Text = 'Movie Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PairMovie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end